classdef ParabolaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ButtonGroup      matlab.ui.container.ButtonGroup
        dis_contain      matlab.ui.control.RadioButton
        contain          matlab.ui.control.RadioButton
        dis_select       matlab.ui.control.RadioButton
        Slider_degree    matlab.ui.control.Slider
        Slider_2Label_3  matlab.ui.control.Label
        Slider_weight    matlab.ui.control.Slider
        Slider_2Label_2  matlab.ui.control.Label
        Slider_k         matlab.ui.control.Slider
        Label_2          matlab.ui.control.Label
        Button           matlab.ui.control.Button
        Label_title      matlab.ui.control.Label
        Slider_speed     matlab.ui.control.Slider
        msLabel          matlab.ui.control.Label
        Slider_height    matlab.ui.control.Slider
        Label            matlab.ui.control.Label
        UIAxes_XY        matlab.ui.control.UIAxes
        UIAxes_Y         matlab.ui.control.UIAxes
        UIAxes_X         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        share_height  % 设置的高度数据
        share_speed   % 设置的初速度数据
       share_weight %设置的初始质量
       share_k %设置的初始比例系数
       share_select %开关状态
       share_degree %初始角度
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider_height
        function Slider_heightValueChanged(app, event)
            app.share_height = app.Slider_height.Value;
        end

        % Value changed function: Slider_speed
        function Slider_speedValueChanged(app, event)
            app.share_speed = app.Slider_speed.Value;
        end

        % Value changed function: Slider_degree
        function Slider_degreeValueChanged(app, event)
            app.share_degree = app.Slider_degree.Value;
            
        end

        % Value changed function: Slider_weight
        function Slider_weightValueChanged(app, event)
            app.share_weight = app.Slider_weight.Value;
           
        end

        % Value changed function: Slider_k
        function Slider_kValueChanged(app, event)
           app.share_k = app.Slider_k.Value;
     
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.share_select = app.ButtonGroup.SelectedObject;
            if app.share_select==app.dis_contain
                app.Slider_degree.Enable='off';
                app.Slider_weight.Enable='off';
                app.Slider_k.Enable='off';
            else
                app.Slider_degree.Enable='on';
                app.Slider_weight.Enable='on';
                app.Slider_k.Enable='on';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
               value_height=app.share_height;
               value_speed = app.share_speed;
               value_weight=app.share_weight;
               value_k=app.share_k;
               value_degree=app.share_degree;
               value_select=app.share_select;
            if  value_select==app.dis_contain

                   formula_X= @(t)value_speed.*t;
                   formula_Y=@(t)value_height-9.8/2*t.^2;
                   formula_XY=@(x)value_height-(9.8/(2*value_speed^2))*x.^2;
                   %指定高度下回到地面的时间            
                    sol=fzero(formula_Y,1);
                    time=sol(sol>0);
                    t=linspace(0,time,100);
                    x=t*value_speed;
        
                   plot(app.UIAxes_X,t,formula_X(t),LineWidth=2);
                   grid(app.UIAxes_X,"on");
                   plot(app.UIAxes_Y,t,formula_Y(t),LineWidth=2);
                   grid(app.UIAxes_Y,'on');
                   plot(app.UIAxes_XY,x,formula_XY(x),LineWidth=2);
                   grid(app.UIAxes_XY,'on');

            else


                    cla(app.UIAxes_X)
                    cla(app.UIAxes_Y)
                    cla(app.UIAxes_XY)
    
                    formula_XK=@(t) (value_weight/value_k)*(value_speed*cos(deg2rad(value_degree)))*(1-exp(-(value_k/value_weight)*t));
                    formula_YK=@(t)(value_weight/value_k)*(value_speed*sin(deg2rad(value_degree))+value_weight*9.8/value_k)*(1-exp(-(value_k/value_weight)*t))-(value_weight*9.8)*t/value_k;

                   sol=fzero(formula_YK,5);
                   time=sol(sol>0);
                   t=linspace(0,time,100);
    
                   plot(app.UIAxes_X,t,formula_XK(t),LineWidth=2);
                   grid(app.UIAxes_X,"on");
                   plot(app.UIAxes_Y,t,formula_YK(t),LineWidth=2);
                   grid(app.UIAxes_Y,'on');
                   plot(app.UIAxes_XY,formula_XK(t),formula_YK(t),LineWidth=2);
                   grid(app.UIAxes_XY,'on');
    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'gray');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_X = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_X, '时间(s)')
            ylabel(app.UIAxes_X, '水平方向(m)')
            zlabel(app.UIAxes_X, 'Z')
            colormap(app.UIAxes_X, 'gray')
            app.UIAxes_Y = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_Y, '时间(s)')
            ylabel(app.UIAxes_Y, '竖直方向(m)')
            zlabel(app.UIAxes_Y, 'Z')
            colormap(app.UIAxes_Y, 'gray')
            app.UIAxes_XY = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_XY, '水平方向(m)')
            ylabel(app.UIAxes_XY, '竖直方向(m)')
            zlabel(app.UIAxes_XY, 'Z')
            colormap(app.UIAxes_XY, 'gray')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '高度(m)';
            app.Slider_height = uislider(app.UIFigure);
            app.Slider_height.ValueChangedFcn = createCallbackFcn(app, @Slider_heightValueChanged, true);
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = '初速度(m/s)';
            app.Slider_speed = uislider(app.UIFigure);
            app.Slider_speed.ValueChangedFcn = createCallbackFcn(app, @Slider_speedValueChanged, true);
            app.Label_title = uilabel(app.UIFigure);
            app.Label_title.Text = '调整各项参数数值，观察图像变化';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始模拟';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '比例系数';
            app.Slider_k = uislider(app.UIFigure);
            app.Slider_k.Limits = [0 1];
            app.Slider_k.ValueChangedFcn = createCallbackFcn(app, @Slider_kValueChanged, true);
            app.Slider_2Label_2 = uilabel(app.UIFigure);
            app.Slider_2Label_2.Text = '质量(kg)';
            app.Slider_weight = uislider(app.UIFigure);
            app.Slider_weight.Limits = [0 1];
            app.Slider_weight.ValueChangedFcn = createCallbackFcn(app, @Slider_weightValueChanged, true);
            app.Slider_2Label_3 = uilabel(app.UIFigure);
            app.Slider_2Label_3.Text = '角度';
            app.Slider_degree = uislider(app.UIFigure);
            app.Slider_degree.Limits = [0 90];
            app.Slider_degree.ValueChangedFcn = createCallbackFcn(app, @Slider_degreeValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '空气阻力';
            app.dis_select = uiradiobutton(app.ButtonGroup);
            app.dis_select.Text = '无选择';
            app.dis_select.Value = true;
            app.contain = uiradiobutton(app.ButtonGroup);
            app.contain.Text = '含阻力';
            app.dis_contain = uiradiobutton(app.ButtonGroup);
            app.dis_contain.Text = '无阻力';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParabolaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end