classdef ParabolaApp_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        Language_Menu      matlab.ui.container.Menu
        uipanel3           matlab.ui.container.Panel
        Text_X             matlab.ui.control.TextArea
        Text_Vy            matlab.ui.control.TextArea
        text12             matlab.ui.control.Label
        ShowTheoretical    matlab.ui.control.Button
        text9              matlab.ui.control.Label
        text2              matlab.ui.control.Label
        axes17             matlab.ui.control.UIAxes
        axes15             matlab.ui.control.UIAxes
        uipanel6           matlab.ui.container.Panel
        uipanel5           matlab.ui.container.Panel
        DataCursorMode     matlab.ui.control.StateButton
        uipanel4           matlab.ui.container.Panel
        edit_length_pixel  matlab.ui.control.EditField
        text5              matlab.ui.control.Label
        text4              matlab.ui.control.Label
        edit_length_cm     matlab.ui.control.EditField
        UnitConversion     matlab.ui.control.Button
        LengthMeasurement  matlab.ui.control.StateButton
        axes6              matlab.ui.control.UIAxes
        axes3              matlab.ui.control.UIAxes
        axes2              matlab.ui.control.UIAxes
        uipanel1           matlab.ui.container.Panel
        uipanel7           matlab.ui.container.Panel
        text13             matlab.ui.control.Label
        ExtractBall_LAB    matlab.ui.control.Button
        ExtractBall_HSV    matlab.ui.control.Button
        ExtractBall_RGB    matlab.ui.control.Button
        LoadAndTrim        matlab.ui.control.Button
        FrameSlider        matlab.ui.control.Slider
        axes1              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function LanguageItemsCallback(app, hObject, eventdata, handles)
            
            set(handles.LanguageItems,'Checked','off');
            hObject.Checked = 'on';
            lang = hObject.UserData;
            if ~isequal(getpref('ParabolaApp','lang'),lang)
                setpref('ParabolaApp','lang',lang)
                uiwait(msgbox(['Changed language setting to ',hObject.Text],'modal'))
            
                updateLanguage(app, handles)
            end
        end
        
        function [xx, yy] = calcRangeData(app, minValDeg, maxValDeg)
            th = 0:360;
            r = 1;
            if minValDeg <= maxValDeg
                id = th >= minValDeg & th <= maxValDeg;
            else
                id = [find(th >= minValDeg) find(th <= maxValDeg)];
            end
            
            xx = r*cosd([minValDeg th(id) maxValDeg]);
            yy = r*sind([minValDeg th(id) maxValDeg]);
        end
        
        function txt = datacursorDisplayFcn(app, obj, event_obj, handles)
            % Customizes text of data tips
            pos = get(event_obj,'Position');
            switch get(get(event_obj,'Target'),'Parent')
                case handles.axes2
                    txt = {[msg('DataCursorTime'),num2str(pos(1))],...
                        ['x: ',num2str(pos(2))]};
                case handles.axes3
                    txt = {[msg('DataCursorTime'),num2str(pos(1))],...
                        ['y: ',num2str(pos(2))]};
                case handles.axes6
                    txt = {['x: ',num2str(pos(1))],...
                        ['y: ',num2str(pos(2))]};
            end
        end
        
        function track_object(app, hObject, handles)
            % --- Executes on button press in pushbutton2.
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % imtool close
            %% 各种System Objects生成 %%%%%
            % 为视频加载创建对象
            
            % obj.reader = vision.VideoFileReader('IMG_0257.MOV');
            
            % if ~isfield(handles,'frames_rgb') || isempty(handles.frames_rgb)
            %     h = waitbar(1,'准备中...');
            %     handles.frames_rgb = read(handles.vidObj);
            %     handles.frames_lab = rgb2lab(handles.frames_rgb);
            %     guidata(hObject,handles)
            %     delete(h)
            % end
            
            %reset(handles.vidObj)
            
            % videoWriter = vision.VideoFileWriter('result.mp4','FileFormat','MPEG4');
            % 生成用于显示动画的对象
            % obj.videoPlayer = vision.VideoPlayer('Position', [ 120, 100, 700, 430]);
            
            % % 创建用于 blob 分析的对象（中心点、边界框）
            % obj.blobAnalyser = vision.BlobAnalysis( 'AreaOutputPort',false, ...
            %     'CentroidOutputPort', true,  'BoundingBoxOutputPort', true, ...
            %     'MinimumBlobArea', 20, 'ExcludeBorderBlobs',true);
            handles.blobAnalyser = vision.BlobAnalysis( 'AreaOutputPort',false, ...
                'CentroidOutputPort', true,  'BoundingBoxOutputPort', true, ...
                'MinimumBlobArea', 20, 'ExcludeBorderBlobs',true);
            
            % 轨迹的初始化（每个轨迹存储每个移动物体的信息）
            tracks = struct(...
                'id',           {}, ...       % ID番号
                'bbox',         {}, ...       %当前帧中的边界框（用于显示）
                'kalmanFilter', {}, ...       %用于此对象跟踪的卡尔曼滤波器
                'age',               {}, ...  %自首次检测以来的帧数
                'totalVisibleCount', {}, ...      %检测到的帧总数 => 显示是否超过阈值
                'consecutiveInvisibleCount', {}); %连续未检测到的帧数=>如果超过阈值则删除该轨道
            
            nextId = 1; % ID of the next track
            centroidLog = [];
            
            %%
            % minVal = handles.minVal2;
            % maxVal = handles.maxVal2;
            %% 主循环 %%%%%
            hWait = waitbar(1,msg('TrackingObject'));
            switch handles.colorType
                case 'rgb'
                    use_frames = handles.vidData;
                case 'hsv'
                    [a,b,c,d] = size(handles.vidData);
                    tmp = mat2cell(handles.vidData,a,b,c,ones(1,d));
                    xx = cat(1,tmp{:});
                    xxx = mat2cell(rgb2hsv(xx),a*ones(1,d),b,c);
                    use_frames = cat(4,xxx{:});
            
                    %use_frames = rgb2hsv(handles.vidData);
                case 'lab'
                    use_frames = rgb2lab(handles.vidData);
            end
            delete(hWait)
            for iFrame = 1:size(handles.vidData,4)
            %while ( isDone(handles.vidObj)==false)
                frame_rgb = handles.vidData(:,:,:,iFrame);     % 读取 1 帧
                    %% 【检测画面中所有运动物体】 %%%%%
                % Detect objects in the frame: Detect moving objects (areas): mask为1前景，0为背景
                switch handles.colorType
                    case 'rgb'
                        mask = createMask_RGB(use_frames(:,:,:,iFrame),handles.RGB_Thresh);
                    case 'hsv'
                        mask = createMask_HSV(frame_rgb,use_frames(:,:,:,iFrame),handles.HSV_Thresh(1),handles.HSV_Thresh(2));
                    case 'lab'
                        mask = createMask_LAB(frame_rgb,use_frames(:,:,:,iFrame),handles.LAB_Thresh);
                end
                %mask = createMask3(handles.frames_lab(:,:,:,iFrame),minVal,maxVal);
                 mask = bwareafilt(mask,1);
                % 中心点/边界框检测
                [centroids, bboxes] = step(handles.blobAnalyser, mask);
            
                %% [根据前一帧预测当前帧中的位置] %%%%%
                % 使用卡尔曼滤波器预测在前一帧中检测到的每个对象（轨迹）的位置
                for i = 1:length(tracks)
                    bbox = tracks(i).bbox;    % 前一帧中的边界框
                    % 预测当前帧中的位置
                    predictedPosition = int32(predict(tracks(i).kalmanFilter));
                    % 将边界框中心调整到预测的质心位置
                    tracks(i).bbox = [predictedPosition - bbox(3:4)/2, bbox(3:4)];
            
                end
            
                %% 将检测到的对象与已检测到的对象（轨迹）相关联%%%%%
                % 成本的计算：预测已经检测到的物体的位置和每个检测到的物体的距离
                cost = zeros(length(tracks), size(centroids, 1));         % 轨迹数 x 检测数
                for i = 1:length(tracks)
                    cost(i, :) = distance(tracks(i).kalmanFilter, centroids);
                end
            
                % 将检测到的对象分配给每个轨道（匈牙利算法：最小化总成本）
                % assignment：分配一组track number和detection number
                costOfNonAssignment = 87;     %如果它很小，则不会将更多轨道分配给现有轨道 => 将生成许多新轨道
                [assignments, unassignedTracks, unassignedDetections] = ...
                    assignDetectionsToTracks(cost, costOfNonAssignment);
            
                %% 找到对应对象的轨迹信息更新 %%%%%
                for i = 1:size(assignments, 1)
                    trackIdx = assignments(i, 1);           %追踪号码
                    detectionIdx = assignments(i, 2);       %检测对象数
                    centroid = centroids(detectionIdx, :);  %提取该检测到的对象的中心点（obj.blobAnalyser.step(mask)使用（掩码）的结果）
                    bbox = bboxes(detectionIdx, :);         %提取检测到的对象的边界框（obj.blobAnalyser.step使用（掩码）的结果)
                    % 使用检测到的位置和预测值估计当前位置
                    correct(tracks(trackIdx).kalmanFilter, centroid);
            
                    % 更新每个轨道信息：边界框、年龄、totalVisibleCount、conservativeInvisibleCount
                    tracks(trackIdx).bbox = bbox;
                    tracks(trackIdx).age = tracks(trackIdx).age + 1;
                    tracks(trackIdx).totalVisibleCount = ...
                        tracks(trackIdx).totalVisibleCount + 1;
                    tracks(trackIdx).consecutiveInvisibleCount = 0;
                end
                % 轨迹生成数据
                if isempty(assignments)
                    centroidLog = centroids;
                else
                    [~,idx] = sort(assignments(:,1));
                    centroidLog = [centroidLog centroids(assignments(idx,2),:)];
                end
            
                %% 未找到对应对象的轨道信息更新%%%%%
                % 年龄、连续InvisibleCount          
                for i = 1:length(unassignedTracks)
                    ind = unassignedTracks(i);
                    tracks(ind).age = tracks(ind).age + 1;
                    tracks(ind).consecutiveInvisibleCount = ...
                        tracks(ind).consecutiveInvisibleCount + 1;
                end
            
            
                %% 擦除丢失的曲目 %%%%%
                % 在不观察连续 20 帧或更多帧的情况下从轨道中删除
                if ~isempty(tracks)
                    % 计算它可见的轨道年龄的分数
                    ages = [tracks(:).age];
                    totalVisibleCounts = [tracks(:).totalVisibleCount];
                    visibility = totalVisibleCounts ./ ages;     % 在所有年龄段观察到的框架百分比
            
                    %找到“丢失”轨道的索引
                    lostInds = (ages < 8 & visibility < 0.6) | ...
                        ([tracks(:).consecutiveInvisibleCount] >= 100);   %连续20帧未观察后删除
            
                    tracks = tracks(~lostInds);   %擦除丢失的曲目
                end
            
                %% 为新发现的对象生成新轨迹 %%%%%
                %(此处未分配的曲目将被视为新曲目。)
                centroids1 = centroids(unassignedDetections, :);           % Nx2 double
                bboxes = bboxes(unassignedDetections, :);                 % Nx4 int32
            
                for i = 1:size(centroids1, 1)
            
                    centroid = centroids1(i,:);
                    bbox = bboxes(i, :);
            
                    % 为每个新对象生成一个卡尔曼滤波器
                    % http://www.mathworks.cn/videos/introduction-to-kalman-filters-for-object-tracking-79674.html
                    kalmanFilter = configureKalmanFilter('ConstantVelocity', ...
                        centroid, [200, 50], [100, 25], 100);
            
                    % 生成新曲目
                    newTrack = struct(...
                        'id', nextId, ...
                        'bbox', bbox, ...
                        'kalmanFilter', kalmanFilter, ...
                        'age', 1, ...
                        'totalVisibleCount', 1, ...
                        'consecutiveInvisibleCount', 0);
            
                    % 将生成的新轨道附加到轨道数组的末尾
                    tracks(end + 1) = newTrack;
            
                    %将 nextId 加一
                    nextId = nextId + 1;
                end
            
            
                %% [结果展示] %%%%%
                frame_rgb = im2uint8(frame_rgb);
                mask = uint8(repmat(mask, [1, 1, 3])) .* 255;  %转换为 uint8 RGB
            
                if ~isempty(tracks)
            
                    reliableTrackInds = ...
                        [tracks(:).totalVisibleCount] < 8;         % 如果检测次数小于8次，则暂不显示。
                    reliableTracks = tracks(reliableTrackInds);
            
                    % 显示对象。 如果未检测到物体
                    % 在此框架中，显示其预测的边界框。
                    if ~isempty(reliableTracks)
                        % 提取边界框坐标
                        bboxes = cat(1, reliableTracks.bbox);
            
                        % 获取 ID
                        ids = int32([reliableTracks(:).id]);
            
                        % 为指示对象的对象创建标签
                        % 我们显示预测的而不是实际的
                        % 位置
                        labels = cellstr(int2str(ids'));
                        predictedTrackInds = ...
                            [reliableTracks(:).consecutiveInvisibleCount] > 0;
                        isPredicted = cell(size(labels));
                        isPredicted(predictedTrackInds) = {' predicted'};
                        labels = strcat(labels, isPredicted);
            
                        % 在RGB框内画一个方框
                        frame_rgb = insertObjectAnnotation(frame_rgb, 'rectangle', ...
                            bboxes, labels);
            
                        % 在蒙版图像中画一个矩形框
                        mask = insertObjectAnnotation(mask, 'rectangle', ...
                            bboxes, labels);
                        % 在蒙版图像中显示带有"o"的质心
                        mask = insertMarker(mask, centroids, 'plus');     % 绿
                        mask = insertMarker(mask, centroids1, 'plus', 'Color','red'); % 红
                    end
                end
            
                % 画出轨迹
                if size(centroidLog,2) >= 30
                    frame_rgb = insertShape(frame_rgb,'line',centroidLog,'LineWidth',5);
                end
            
                handles.procVidData(:,:,:,iFrame) = frame_rgb;
            
                % 表示
                %step(videoWriter,frame);
                %drawnow;             % 确认按钮事件
                showFrameOnAxis(handles.axes1, frame_rgb) % 绘制于实际视频上
                %drawnow; % myzk
            end
            %release(handles.vidObj);
            % release(videoWriter);
            
            handles.isProcessed = true;
            
            x = centroidLog(:,1:2:end)';
            y = 480-centroidLog(:,2:2:end)';
            
            if isempty(x)
                uiwait(msgbox(msg('CouldNotDetect')))
                return
            end
            
            x = x - x(1);
            y = y - y(1);
            
            % plot(x,y)绘制水平与铅锤图像
            plot(handles.axes6,x,y,'o');
            grid(handles.axes6,'on')
            title(handles.axes6,msg('TrajectoryLabel')); xlabel(handles.axes6,msg('HorizontalPixel')); ylabel(handles.axes6,msg('VerticalPixel'));
            
            % plot(t,x)仅仅绘制水平方向
            %S = handles.vidObj.info;
            t = ((0:(size(centroidLog,2)/2-1))/handles.FrameRate)';
            plot(handles.axes2,t,x,'o');
            grid(handles.axes2,'on')
            ylabel(handles.axes2,msg('HorizontalPixel'));
            xticklabels(handles.axes2,[])
            
            % plot(t,y)仅仅绘制铅锤方向
            plot(handles.axes3,t,y,'o');
            xlabel(handles.axes3,msg('TimeLabel')); ylabel(handles.axes3,msg('VerticalPixel'));
            grid(handles.axes3,'on')
            
            handles.t_all = t;
            handles.x_all = x;
            handles.y_all = y;
            guidata(hObject,handles)
            
            assignin('base','t',t)
            assignin('base','x',x)
            assignin('base','y',y)
            
            handles.UnitConversion.Enable = 'on';
            handles.LengthMeasurement.Enable = 'on';
            handles.DataCursorMode.Enable = 'on';
        end
        
        function updateLanguage(app, handles)
            
            handles.figure1.Name = msg('figure1Name');
            handles.LoadAndTrim.String = msg('LoadAndTrimButton');
            handles.uipanel7.Title = msg('uipanel7Title');
            handles.text13.String = msg('text13String');
            handles.text12.String = msg('text12String');
            handles.text2.String = msg('text2String');
            handles.text9.String = msg('text9String');
            handles.text4.String = msg('text4String');
            handles.text5.String = msg('text5String');
            handles.uipanel1.Title = msg('uipanel1Title');
            handles.uipanel3.Title = msg('uipanel3Title');
            handles.uipanel6.Title = msg('uipanel6Title');
            handles.uipanel5.Title = msg('uipanel5Title');
            handles.ShowTheoretical.String = msg('ShowTheoreticalButton');
            handles.LengthMeasurement.String = msg('LengthMeasurementButton');
            handles.UnitConversion.String = msg('UnitConversionButton');
            handles.DataCursorMode.String = msg('DataCursorModeButton');
            
            handles.LoadAndTrim.FontName = msg('FontName');
            handles.uipanel7.FontName = msg('FontName');
            handles.text13.FontName = msg('FontName');
            handles.text12.FontName = msg('FontName');
            handles.text2.FontName = msg('FontName');
            handles.text9.FontName = msg('FontName');
            handles.text4.FontName = msg('FontName');
            handles.text5.FontName = msg('FontName');
            handles.uipanel1.FontName = msg('FontName');
            handles.uipanel4.FontName = msg('FontName');
            handles.uipanel3.FontName = msg('FontName');
            handles.uipanel6.FontName = msg('FontName');
            handles.uipanel5.FontName = msg('FontName');
            handles.ShowTheoretical.FontName = msg('FontName');
            handles.LengthMeasurement.FontName = msg('FontName');
            handles.UnitConversion.FontName = msg('FontName');
            handles.DataCursorMode.FontName = msg('FontName');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ParabolaApp_OpeningFcn(app, varargin)
            % --- 在 ParabolaApp 可见之前执行。
            
            % 确保应用程序在运行时出现在屏幕上
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ParabolaApp (see VARARGIN)
            
            if ~ispref('ParabolaApp','lang')
                btn = questdlg('Choose language','Language','English','中文','中文');
                if isempty(btn)
                    lang = 'cn';
                else
                    switch btn
                        case 'English'
                            lang = 'en';
                        case '中文'
                            lang = 'cn';
                    end
                end
                setpref('ParabolaApp','lang',lang)
            end
            
            [langCode, langName] = languages;
            langPref = getpref('ParabolaApp','lang');
            for id = 1:length(langCode)
                handles.LanguageItems(id) = uimenu(handles.Language_Menu,'Text',langName{id},'UserData',langCode{id});
                if strcmpi(langCode{id},langPref)
                    handles.LanguageItems(id).Checked = 'on';
                else
                    handles.LanguageItems(id).Checked = 'off';
                end
            end
            
            set(handles.LanguageItems,'MenuSelectedFcn',{@app.LanguageItemsCallback,handles})
            
            updateLanguage(app, handles)
            
            handles.RGB_Thresh = [0 255 0 255 0 255];
            handles.HSV_Thresh = [0 1];
            handles.LAB_Thresh = [-50 50];
            
            handles.isProcessed = false;
            
             imshow('x_equals.png','Parent',handles.axes15)
           imshow('y_equals.png','Parent',handles.axes17)


            % 为 ParabolaApp 选择默认命令行输出
            handles.output = hObject;
            
            % 更新句柄结构
            guidata(hObject, handles);
            
            movegui(hObject,'north')
        end

        % Value changed function: DataCursorMode
        function DataCursorMode_Callback(app, event)
            % ---在 DataCursorMode 中按下按钮时执行。
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to DataCursorMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of DataCursorMode
            if get(hObject,'Value')==1
                dcmObj = datacursormode(handles.figure1);
                set(dcmObj,'UpdateFcn',{@app.datacursorDisplayFcn,handles})
                set(dcmObj,'Enable','on')
                set(hObject,'String',msg('EndDataInspection'),'ForegroundColor','r','FontWeight','bold')
                handles.UnitConversion.Enable = 'off';
                handles.LengthMeasurement.Enable = 'off';
                handles.ShowTheoretical.Enable = 'off';
                handles.LoadAndTrim.Enable = 'off';
                handles.ExtractBall_RGB.Enable = 'off';
                handles.ExtractBall_HSV.Enable = 'off';
                handles.ExtractBall_LAB.Enable = 'off';
            
            else
                dcmObj = datacursormode(handles.figure1);
                removeAllDataCursors(dcmObj)
                set(dcmObj,'Enable','off')
                set(hObject,'String',msg('StartDataInspection'),'ForegroundColor','k','FontWeight','normal')
                handles.UnitConversion.Enable = 'on';
                handles.LengthMeasurement.Enable = 'on';
                handles.ShowTheoretical.Enable = 'on';
                handles.LoadAndTrim.Enable = 'on';
                handles.ExtractBall_RGB.Enable = 'on';
                handles.ExtractBall_HSV.Enable = 'on';
                handles.ExtractBall_LAB.Enable = 'on';
            end
        end

        % Button pushed function: ExtractBall_HSV
        function ExtractBall_HSV_Callback(app, event)
            % --- 在 ExtractBall_HSV 中按下按钮时执行。
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ExtractBall_HSV (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            thresh = HSV_Threshold_Tool(handles.vidData(:,:,:,round(handles.FrameSlider.Value)),handles.HSV_Thresh);
            if isempty(thresh)
                return
            end
            
            handles.HSV_Thresh = thresh;
            handles.colorType = 'hsv';
            
            guidata(hObject,handles)
            
            track_object(app, hObject,handles)
        end

        % Button pushed function: ExtractBall_LAB
        function ExtractBall_LAB_Callback(app, event)
            % --- 在 ExtractBall_LAB 中按下按钮时执行。
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ExtractBall_LAB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %调用函数LAB_Threshold_Tool.m进行图像识别
            thresh = LAB_Threshold_Tool(handles.vidData(:,:,:,round(handles.FrameSlider.Value)),handles.LAB_Thresh);
            if isempty(thresh)
                return
            end
            
            handles.LAB_Thresh = thresh;
            handles.colorType = 'lab';
            
            guidata(hObject,handles)
            
            track_object(app, hObject,handles)
        end

        % Button pushed function: ExtractBall_RGB
        function ExtractBall_RGB_Callback(app, event)
            % --- 在 ExtractBall_RGB 中按下按钮时执行。
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ExtractBall_RGB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            thresh = RGB_Threshold_Tool(handles.vidData(:,:,:,round(handles.FrameSlider.Value)),handles.RGB_Thresh);
            if isempty(thresh)
                return
            end
            handles.RGB_Thresh = thresh;
            handles.colorType = 'rgb';
            
            guidata(hObject,handles)
            
            track_object(app, hObject,handles)
        end

        % Value changed function: FrameSlider
        function FrameSlider_Callback(app, event)
            % ---   在滑块移动时执行。
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FrameSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            val = round(get(hObject,'Value'));
            if handles.isProcessed
                handles.hVideoImage.CData = handles.procVidData(:,:,:,val);
            else
                handles.hVideoImage.CData = handles.vidData(:,:,:,val);
            end
        end

        % Value changed function: LengthMeasurement
        function LengthMeasurement_Callback(app, event)
            % --- Executes on button press in LengthMeasurement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to LengthMeasurement (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of LengthMeasurement
            
            if hObject.Value == 1
                set(hObject,'String',msg('LengthMeasurementButtonFinish'),'ForegroundColor','r','FontWeight','bold')
                hd = imdistline(handles.axes1);
                handles.hd = hd;
                guidata(hObject,handles)
                handles.UnitConversion.Enable = 'off';
                handles.DataCursorMode.Enable = 'off';
                handles.ShowTheoretical.Enable = 'off';
                handles.LoadAndTrim.Enable = 'off';
                handles.ExtractBall_RGB.Enable = 'off';
                handles.ExtractBall_HSV.Enable = 'off';
                handles.ExtractBall_LAB.Enable = 'off';
            else
                set(hObject,'String',msg('LengthMeasurementButton'),'ForegroundColor','k','FontWeight','normal')
                hd = handles.hd;
                dist = getDistance(hd);
                set(handles.edit_length_pixel,'String',num2str(dist))
                delete(hd)
                handles.hd = [];
                guidata(hObject,handles)
                handles.UnitConversion.Enable = 'on';
                handles.DataCursorMode.Enable = 'on';
                handles.ShowTheoretical.Enable = 'on';
                handles.LoadAndTrim.Enable = 'on';
                handles.ExtractBall_RGB.Enable = 'on';
                handles.ExtractBall_HSV.Enable = 'on';
                handles.ExtractBall_LAB.Enable = 'on';
            end
        end

        % Button pushed function: LoadAndTrim
        function LoadAndTrim_Callback(app, event)
            % --- Executes on button press in LoadAndTrim.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to LoadAndTrim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            out = trim_video;
            if isempty(out)
                return
            end
            handles.vidData = out{1};
            handles.FrameRate = out{2};
            handles.procVidData = zeros(size(handles.vidData),class(handles.vidData));
            if ~isempty(handles.vidData)
                handles.hVideoImage = imshow(handles.vidData(:,:,:,1),'Parent',handles.axes1);
                handles.FrameSlider.Min = 1;
                handles.FrameSlider.Max = size(handles.vidData,4);
                handles.FrameSlider.Value = 1;
                handles.FrameSlider.SliderStep = [1/(handles.FrameSlider.Max-1) 10/(handles.FrameSlider.Max-1)];
            
                handles.isProcessed = false;
            
                cla(handles.axes2)
                cla(handles.axes3)
                cla(handles.axes6)
            
                handles.edit_length_pixel.String = '0';
                handles.edit_length_cm.String = '0';
            
                handles.LengthMeasurement.Enable = 'off';
                handles.UnitConversion.Enable = 'off';
                handles.DataCursorMode.Enable = 'off';
            
                handles.ShowTheoretical.Enable = 'off';
            
                handles.ExtractBall_RGB.Enable = 'on';
                handles.ExtractBall_HSV.Enable = 'on';
                handles.ExtractBall_LAB.Enable = 'on';
            
                handles.FrameSlider.Enable = 'on';
            
                % 阈值初始化
                handles.RGB_Thresh = [0 255 0 255 0 255];
                handles.HSV_Thresh = [0 1];
                handles.LAB_Thresh = [-50 50];
            
                set(hObject,'FontWeight','normal','ForegroundColor','k')
            
                guidata(hObject, handles)
            end
        end

        % Button pushed function: ShowTheoretical
        function ShowTheoretical_Callback(app, event)
            % --- 执行按钮按下显示理论。
            
            % 创建 GUIDE 样式的回调参数-由迁移工具添加
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    句柄显示理论值
            % eventdata  reserved -将在未来版本的 MATLAB 中定义
            % handles    带句柄和用户数据的结构 (see GUIDATA)
            %
            
            pixel_dist = str2double(get(handles.edit_length_pixel,'String'));
            actual_dist = str2double(get(handles.edit_length_cm,'String'));
            
            if isnan(pixel_dist) || isnan(actual_dist) || pixel_dist == 0 || actual_dist == 0
                uiwait(msgbox(msg('ConvertFromPixelToCM'),'modal'))
                return
            end
            
%             try
%                 x_t_fcn_m = str2func(['@(t)' get(handles.edit_vx0,'String')]);
%                 y_t_fcn_m = str2func(['@(t)' get(handles.edit_vy0,'String')]);
%             
%                 % 转换为厘米/秒
%                 x_t_fcn_cm = @(t) 100*x_t_fcn_m(t);
%                 y_t_fcn_cm = @(t) 100*y_t_fcn_m(t);
%             
%                 % 测试匿名函数是否可以执行
%                 tmp = x_t_fcn_cm(1);
%                 tmp = y_t_fcn_cm(1);
%             catch
%                 uiwait(errordlg(msg('WrongFormulaEnterAgain'),'Error','modal'))
%                 return
%             end
            
            x_vec = handles.x_all;
            y_vec = handles.y_all;
            
            %time_vec = handles.t_all;
            
            x_vec_actual = x_vec ./pixel_dist .* actual_dist;
            y_vec_actual = y_vec ./pixel_dist .* actual_dist;
            
            cla(handles.axes6)  %删除像素单位的图像
            %% 对抛物线进行拟合

            [xData, yData] = prepareCurveData( x_vec_actual, y_vec_actual );
            % 设置 fittype 和选项。
            ft = fittype( 'a*x-(9.8/(2*b^2))*x^2', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Display = 'Off';
            opts.StartPoint = [0.498364051982143 0.959743958516081];
            
            % 对数据进行模型拟合。
            [fitresult, gof] = fit( xData, yData, ft, opts );
             
%             Vx=fitresult.b;
%             % 生成带有变量 t 和参数 a 的公式字符串
%             formula1 = sprintf('%f*t', Vx);
%             % 将公式设置为 editfield 的值
%             app.edit_vx0.Value= formula1;%Vx函数展示

%              Vy=fitresult.a*fitresult.b;
%             formula2=sprintf('%f*t-9.8/2*t^2',Vy);
%             app.edit_vy0.Value=formula2;%Vy函数展示


            % hLines = plot(handles.axes6,x_vec_actual,y_vec_actual,'o',x_ideal,y_ideal,'-');
            hLines = plot(handles.axes6,x_vec_actual,y_vec_actual,'o');
            title(handles.axes6,'轨迹');
            xlabel(handles.axes6,'水平位置(cm)');ylabel(handles.axes6,'竖直位置(cm)')
            grid(handles.axes6,'on')
            hold(handles.axes6,'on')
            warnState = warning('off','MATLAB:fplot:NotVectorized');
            %hLines(2) = fplot(handles.axes6,x_t_fcn_cm,y_t_fcn_cm,[0 time_vec(end)]);
            hLines(2) = plot(handles.axes6,xData, yData);
            warning(warnState)
            hLines(2).LineWidth = 2;
            hold(handles.axes6,'off')
            legend(handles.axes6,msg('LegendMeasuredData'),msg('LegendTheoretical'),'Location','Northeast')
            
            guidata(hObject, handles)
            
%             f = figure('Units','pixels','Position',[100 100 900 400],...
%                 'Toolbar','none','Menu','none','Name',msg('LetsThinkName'),'NumberTitle','off',...
%                 'Visible','off','WindowStyle','modal');
%             uicontrol('Units','normalized','Position',[0.1 0.1 0.8 0.8],...
%                 'Style','text','FontName',msg('FontName'),'String',msg('LetsThink'),...
%                 'FontSize',20,'FontWeight','bold','ForegroundColor','r','HorizontalAlignment','left')
%             uicontrol('Units','pixels','Position',[750 5 140 30],...
%                 'Style','pushbutton','String','OK','FontName',msg('FontName'),...
%                 'FontSize',16,'FontWeight','bold','Callback',@(o,e) delete(f))
%             movegui(f,'north')
%             f.Visible = 'on';
%             uiwait(f)


            Vx=fitresult.b;
            % 生成带有变量 t 和参数 a 的公式字符串
            formula1 = sprintf('%f*t', Vx);
            % 将公式设置为 editfield 的值
            app.Text_X.Value=formula1;%Vx函数展示
          
            
             Vy=fitresult.a*fitresult.b;
            formula2=sprintf('%f*t-9.8/2*t^2',Vy);
            app.Text_Vy.Value=formula2;%Vy函数展示
        end

        % Button pushed function: UnitConversion
        function UnitConversion_Callback(app, event)
            % --- 在 UnitConversion 中按下按钮时执行。
            
            %   创建 GUIDE 样式的回调参数 - 由迁移工具添加
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to UnitConversion (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of UnitConversion
            pixel_dist = str2double(get(handles.edit_length_pixel,'String'));
            actual_dist = str2double(get(handles.edit_length_cm,'String'));
            
            if isnan(pixel_dist) || isnan(actual_dist) || pixel_dist == 0 || actual_dist == 0
                uiwait(msgbox(msg('ConvertFromPixelToCM'),'modal'))
                return
            end
            
            % (t,x) 图更新,像素与长度转换后图像
            lh2 = get(handles.axes2,'Children');
            tx_vec = handles.x_all';
            tx_vec_actual = tx_vec./pixel_dist .* actual_dist;
            set(lh2,'YData',tx_vec_actual)
            set(handles.axes2,'YLim',[round(min(tx_vec_actual)),ceil(max(tx_vec_actual))])
            ylabel(handles.axes2,msg('HorizontalCM'));
            
            lh3 = get(handles.axes3,'Children');
            ty_vec = handles.y_all';
            ty_vec_actual = ty_vec./pixel_dist .* actual_dist;
            
            set(lh3,'YData',ty_vec_actual)
            set(handles.axes3,'YLim',[round(min(ty_vec_actual)),ceil(max(ty_vec_actual))])
            ylabel(handles.axes3,msg('VerticalCM'));
            
            lh6 = get(handles.axes6,'Children');
            x_vec = handles.x_all'; % pixel
            y_vec = handles.y_all'; % pixel
            
            x_vec_actual = x_vec ./pixel_dist .* actual_dist;
            y_vec_actual = y_vec ./pixel_dist .* actual_dist;
            % set(lh4,'XData',x_vec_actual,'YData',y_vec_actual)
            % set(handles.axes4,'XLim',[round(min(x_vec_actual)),ceil(max(x_vec_actual))])
            % set(handles.axes4,'YLim',[round(min(y_vec_actual)),ceil(max(y_vec_actual))])
            % xlabel(handles.axes4,'X軸位置(cm)');
            % ylabel(handles.axes4,'Y軸位置(cm)');
            
            plot(handles.axes6,x_vec_actual-x_vec_actual(1),y_vec_actual-y_vec_actual(1),'o')
            grid(handles.axes6,'on')
            xlabel(handles.axes6,msg('HorizontalCM'));
            ylabel(handles.axes6,msg('VerticalCM'));
            
            handles.ShowTheoretical.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = '放物運動';
            app.figure1.Tag = 'figure1';
            app.Language_Menu = uimenu(app.figure1);
            app.Language_Menu.Text = '语言';
            app.Language_Menu.Tag = 'Language_Menu';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '1. 素材';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.XLim = [0 1];
            app.axes1.YLim = [0 1];
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.axes1.Tag = 'axes1';
            app.axes1.Visible = 'off';
            app.FrameSlider = uislider(app.uipanel1);
            app.FrameSlider.Limits = [0 1];
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSlider_Callback, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Tag = 'FrameSlider';
            app.FrameSlider.Enable = 'off';
            app.LoadAndTrim = uibutton(app.uipanel1, 'push');
            app.LoadAndTrim.ButtonPushedFcn = createCallbackFcn(app, @LoadAndTrim_Callback, true);
            app.LoadAndTrim.Tag = 'LoadAndTrim';
            app.LoadAndTrim.Text = '导入素材';
            app.uipanel7 = uipanel(app.uipanel1);
            app.uipanel7.Title = '检测目标';
            app.uipanel7.Tag = 'uipanel7';
            app.ExtractBall_RGB = uibutton(app.uipanel7, 'push');
            app.ExtractBall_RGB.ButtonPushedFcn = createCallbackFcn(app, @ExtractBall_RGB_Callback, true);
            app.ExtractBall_RGB.Tag = 'ExtractBall_RGB';
            app.ExtractBall_RGB.Enable = 'off';
            app.ExtractBall_RGB.Text = 'RGB';
            app.ExtractBall_HSV = uibutton(app.uipanel7, 'push');
            app.ExtractBall_HSV.ButtonPushedFcn = createCallbackFcn(app, @ExtractBall_HSV_Callback, true);
            app.ExtractBall_HSV.Tag = 'ExtractBall_HSV';
            app.ExtractBall_HSV.Enable = 'off';
            app.ExtractBall_HSV.Text = 'HSV';
            app.ExtractBall_LAB = uibutton(app.uipanel7, 'push');
            app.ExtractBall_LAB.ButtonPushedFcn = createCallbackFcn(app, @ExtractBall_LAB_Callback, true);
            app.ExtractBall_LAB.Tag = 'ExtractBall_LAB';
            app.ExtractBall_LAB.Enable = 'off';
            app.ExtractBall_LAB.Text = 'L*a*b';
            app.text13 = uilabel(app.uipanel7);
            app.text13.Tag = 'text13';
            app.text13.Text = '尝试不同方法';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = '2. 分析';
            app.uipanel6.Tag = 'uipanel6';
            app.axes2 = uiaxes(app.uipanel6);
            app.axes2.XTickLabel = '';
            app.axes2.YTickLabel = '';
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.uipanel6);
            app.axes3.XTickLabel = '';
            app.axes3.YTickLabel = '';
            app.axes3.Tag = 'axes3';
            app.axes6 = uiaxes(app.uipanel6);
            app.axes6.XTickLabel = '';
            app.axes6.YTickLabel = '';
            app.axes6.Tag = 'axes6';
            app.uipanel4 = uipanel(app.uipanel6);
            app.uipanel4.Title = '[Pixel]-> [cm]';
            app.uipanel4.Tag = 'uipanel4';
            app.LengthMeasurement = uibutton(app.uipanel4, 'state');
            app.LengthMeasurement.ValueChangedFcn = createCallbackFcn(app, @LengthMeasurement_Callback, true);
            app.LengthMeasurement.Tag = 'LengthMeasurement';
            app.LengthMeasurement.Enable = 'off';
            app.LengthMeasurement.Text = '像素间距测量';
            app.UnitConversion = uibutton(app.uipanel4, 'push');
            app.UnitConversion.ButtonPushedFcn = createCallbackFcn(app, @UnitConversion_Callback, true);
            app.UnitConversion.Tag = 'UnitConversion';
            app.UnitConversion.Enable = 'off';
            app.UnitConversion.Text = '像素--[cm] 转换';
            app.edit_length_cm = uieditfield(app.uipanel4, 'text');
            app.edit_length_cm.Tag = 'edit_length_cm';
            app.edit_length_cm.Value = '0';
            app.text4 = uilabel(app.uipanel4);
            app.text4.Tag = 'text4';
            app.text4.Text = '长度(cm)';
            app.text5 = uilabel(app.uipanel4);
            app.text5.Tag = 'text5';
            app.text5.Text = '像素 (Pixel)';
            app.edit_length_pixel = uieditfield(app.uipanel4, 'text');
            app.edit_length_pixel.Tag = 'edit_length_pixel';
            app.edit_length_pixel.Enable = 'off';
            app.edit_length_pixel.Value = '0';
            app.uipanel5 = uipanel(app.uipanel6);
            app.uipanel5.Title = '数据确认';
            app.uipanel5.Tag = 'uipanel5';
            app.DataCursorMode = uibutton(app.uipanel5, 'state');
            app.DataCursorMode.ValueChangedFcn = createCallbackFcn(app, @DataCursorMode_Callback, true);
            app.DataCursorMode.Tag = 'DataCursorMode';
            app.DataCursorMode.Enable = 'off';
            app.DataCursorMode.Text = {'读取数据坐标'; ''};
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = '3. 理论';
            app.uipanel3.Tag = 'uipanel3';
            app.axes15 = uiaxes(app.uipanel3);
            app.axes15.XTick = [];
            app.axes15.YTick = [];
            app.axes15.Tag = 'axes15';
            app.axes17 = uiaxes(app.uipanel3);
            app.axes17.XTick = [];
            app.axes17.YTick = [];
            app.axes17.Tag = 'axes17';
            app.text2 = uilabel(app.uipanel3);
            app.text2.Tag = 'text2';
            app.text2.Text = '水平方向：';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = '铅锤方向：';
            app.ShowTheoretical = uibutton(app.uipanel3, 'push');
            app.ShowTheoretical.ButtonPushedFcn = createCallbackFcn(app, @ShowTheoretical_Callback, true);
            app.ShowTheoretical.Tag = 'ShowTheoretical';
            app.ShowTheoretical.Enable = 'off';
            app.ShowTheoretical.Text = '拟合';
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = '根据目标轨迹进行拟合计算初速度，运动学方程。';
            app.Text_Vy = uitextarea(app.uipanel3);
            app.Text_X = uitextarea(app.uipanel3);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParabolaApp_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)ParabolaApp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end